classdef Hybrid_Promoter_CN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        HillCoefficientSliderLabel  matlab.ui.control.Label
        Hill_Slider                 matlab.ui.control.Slider
        BasalExpressionSliderLabel  matlab.ui.control.Label
        AlphaSlider                 matlab.ui.control.Slider
        PlasmidCopyNumberLabel      matlab.ui.control.Label
        CN_Slider                   matlab.ui.control.Slider
        RBSLabel                    matlab.ui.control.Label
        RBS_Slider                  matlab.ui.control.Slider
        KdSliderLabel               matlab.ui.control.Label
        Kd_Slider                   matlab.ui.control.Slider
        TFRLabel                    matlab.ui.control.Label
        TF_Slider                   matlab.ui.control.Slider
        Kd2Label                    matlab.ui.control.Label
        Kd2_Slider                  matlab.ui.control.Slider
        KdcILabel                   matlab.ui.control.Label
        KdcI_Slider                 matlab.ui.control.Slider
    end

    
    properties (Access = private)
        %Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define
            
            Kd = app.Kd_Slider.Value;
            n = app.Hill_Slider.Value;
            b0 = app.AlphaSlider.Value;
            CN = app.CN_Slider.Value;
            K1 = app.RBS_Slider.Value;
            R = app.TF_Slider.Value;
            kd2 = app.Kd2_Slider.Value;
            kdcI = app.KdcI_Slider.Value;
            
            % Calculate
            N=200;
%             A = logspace(-1,5,N);                                            % Reduce Number Of Points
%             cI = logspace(-1,5,N);
%             [a,ci] = meshgrid(A,cI); 
            
            a = logspace(-1,5,N);                                            % Reduce Number Of Points
            ci = logspace(-1,5,N);
            [A,cI] = meshgrid(a,ci); % Use ‘meshgrid’
            
            %H = @(A,cI) CN.*K1.*(b0 + (1-b0).*( 1./Kd.*((R.*A)./(kd2.*CN)).^n ./ ...
            %    ( 1+ 1./Kd.*((R.*A)./(kd2.*CN)).^n ) ) .*( 1 ./ ( 1+ cI.^2./(kdcI .*CN) ) ) );       % Convert To Anonymous Function
            H = CN.*K1.*(b0 + (1-b0).*( 1./Kd.*((R.*A)./(kd2.*CN)).^n ./ ...
                ( 1+ 1./Kd.*((R.*A)./(kd2.*CN)).^n ) ) .*( 1 ./ ( 1+ cI.^2./(kdcI .*CN) ) ) );       % Convert To Anonymous Function

            plotline2 =  meshc(app.UIAxes,a,ci,H);
            
            % Set line color
            %set(plotline2,'XScale','log','YScale','log');
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Replace 0 in equation with unicode theta
           
            % Remove title, axis labels, and tick labels
            % title(app.UI_Im, []);
            % xlabel(app.UI_Im, []);
            % ylabel(app.UI_Im, []);
            % app.UI_Im.XAxis.TickLabels = {};
            % app.UI_Im.YAxis.TickLabels = {};
            % % Display image and stretch to fill axes
            % I = imshow('Equ_Act.png', 'Parent', app.UIAxes, ...
            %     'XData', [1 app.UI_Im.Position(3)], ...
            %     'YData', [1 app.UI_Im.Position(4)]);
            % % Set limits of axes
            % app.UI_Im.XLim = [0 I.XData(2)];
            % app.UI_Im.YLim = [0 I.YData(2)];
            
            %app.Label.Text = 'Hill(A) = b_0 + k_1 (A^n)/(K^n + A^n)';
            
            % Plot the polar function
            updateplot(app);
        end

        % Callback function
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: Hill_Slider
        function Hill_SliderValueChanged(app, event)
            value = app.Hill_Slider.Value;
            updateplot(app);
            
        end

        % Callback function
        function LigandSliderValueChanged(app, event)
            
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            value = app.AlphaSlider.Value;
            updateplot(app);
        end

        % Value changed function: Kd_Slider
        function Kd_KnobValueChanged(app, event)
            value = app.Kd_Slider.Value;
            updateplot(app);
        end

        % Value changed function: TF_Slider
        function TF_SliderValueChanged(app, event)
            value = app.TF_Slider.Value;
            updateplot(app);
        end

        % Value changed function: RBS_Slider
        function RBS_SliderValueChanged(app, event)
            value = app.RBS_Slider.Value;
            updateplot(app);
        end

        % Value changed function: CN_Slider
        function CN_SliderValueChanged(app, event)
            value = app.CN_Slider.Value;
            updateplot(app);
        end

        % Value changed function: Kd2_Slider
        function Kd2_SliderValueChanged(app, event)
            value = app.Kd2_Slider.Value;
            updateplot(app);
        end

        % Value changed function: KdcI_Slider
        function KdcI_SliderValueChanged(app, event)
            value = app.KdcI_Slider.Value;
            updateplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Hill Function Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Hybrid promoter')
            xlabel(app.UIAxes, {'[A]'; ''})
            ylabel(app.UIAxes, '[cI]')
            app.UIAxes.XLim = [1 100000];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            app.HillCoefficientSliderLabel = uilabel(app.UIFigure);
            app.HillCoefficientSliderLabel.Text = 'Hill Coefficient';
            app.Hill_Slider = uislider(app.UIFigure);
            app.Hill_Slider.Limits = [0.5 4];
            app.Hill_Slider.ValueChangedFcn = createCallbackFcn(app, @Hill_SliderValueChanged, true);
            app.Hill_Slider.Value = 2;
            app.BasalExpressionSliderLabel = uilabel(app.UIFigure);
            app.BasalExpressionSliderLabel.Text = 'Basal Expression';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 0.1;
            app.PlasmidCopyNumberLabel = uilabel(app.UIFigure);
            app.PlasmidCopyNumberLabel.Text = 'Plasmid Copy Number';
            app.CN_Slider = uislider(app.UIFigure);
            app.CN_Slider.Limits = [1 500];
            app.CN_Slider.ValueChangedFcn = createCallbackFcn(app, @CN_SliderValueChanged, true);
            app.CN_Slider.Value = 10;
            app.RBSLabel = uilabel(app.UIFigure);
            app.RBSLabel.Text = 'RBS';
            app.RBS_Slider = uislider(app.UIFigure);
            app.RBS_Slider.Limits = [1 500];
            app.RBS_Slider.ValueChangedFcn = createCallbackFcn(app, @RBS_SliderValueChanged, true);
            app.RBS_Slider.Value = 10;
            app.TFRLabel = uilabel(app.UIFigure);
            app.TFRLabel.Text = 'TF (R)';
            app.TF_Slider = uislider(app.UIFigure);
            app.TF_Slider.Limits = [1 500];
            app.TF_Slider.ValueChangedFcn = createCallbackFcn(app, @TF_SliderValueChanged, true);
            app.TF_Slider.Value = 10;
            app.KdSliderLabel = uilabel(app.UIFigure);
            app.KdSliderLabel.Text = 'Kd';
            app.Kd_Slider = uislider(app.UIFigure);
            app.Kd_Slider.Limits = [0 1000];
            app.Kd_Slider.ValueChangedFcn = createCallbackFcn(app, @Kd_KnobValueChanged, true);
            app.Kd_Slider.Value = 500;
            app.Kd2Label = uilabel(app.UIFigure);
            app.Kd2Label.Text = 'Kd2';
            app.Kd2_Slider = uislider(app.UIFigure);
            app.Kd2_Slider.Limits = [0 1000];
            app.Kd2_Slider.ValueChangedFcn = createCallbackFcn(app, @Kd2_SliderValueChanged, true);
            app.Kd2_Slider.Value = 150;
            app.KdcILabel = uilabel(app.UIFigure);
            app.KdcILabel.Text = 'KdcI';
            app.KdcI_Slider = uislider(app.UIFigure);
            app.KdcI_Slider.Limits = [0 1000];
            app.KdcI_Slider.ValueChangedFcn = createCallbackFcn(app, @KdcI_SliderValueChanged, true);
            app.KdcI_Slider.Value = 400;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Hybrid_Promoter_CN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end